classdef LayeredMedia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiltrospticosdemediosmulticapaLayeredMediaUIFigure  matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        Label_3                     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        ButtonGroup                 matlab.ui.container.ButtonGroup
        LongitudesButtonGroup       matlab.ui.container.ButtonGroup
        LambdaInicialEditField      matlab.ui.control.NumericEditField
        LambdaFinalEditField        matlab.ui.control.NumericEditField
        LongitudDesplegable         matlab.ui.control.DropDown
        Valorde_inicialLabel        matlab.ui.control.Label
        LambdaFinalDesplegable      matlab.ui.control.DropDown
        Valorde_finalLabel          matlab.ui.control.Label
        LambdaInicialDesplegable    matlab.ui.control.DropDown
        LongitudporcapaLabel        matlab.ui.control.Label
        LongitudporcapaEditField    matlab.ui.control.NumericEditField
        GraficasButtonGroup         matlab.ui.container.ButtonGroup
        PrButton                    matlab.ui.control.RadioButton
        PtButton                    matlab.ui.control.RadioButton
        AmbasButton                 matlab.ui.control.RadioButton
        GraficarButton              matlab.ui.control.Button
        AyudaButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        CantidaddecapasSliderLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        DatosadicionalesLabel       matlab.ui.control.Label
        Prueba                      matlab.ui.control.TextArea
        ImpedanciasintrnsecasButtonGroup  matlab.ui.container.ButtonGroup
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Medio1EditFieldLabel        matlab.ui.control.Label
        Medio1EditField             matlab.ui.control.NumericEditField
        Medio2EditFieldLabel        matlab.ui.control.Label
        Medio2EditField             matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            medio1 = app.Medio1EditField.Value; % Recupera el valor de la impedancia intrínseca del medio 1
            medio2 = app.Medio2EditField.Value; % Recupera el valor de la impedancia intrínseca del medio 2
            etaEQ = 0+1i*0; % Variable que lleva control de cuánto es el valor de la capa equivalente anterior
            gamma = 0; % Valor de gamma
            Pr = 0; % Valor de potencia reflejada
            Pt = 0; % Valor de potencia transmitida

            
            valor = app.Slider.Value; % Recupera el valor del slider
            valor = round(valor); % Lo redondea, ya que no hay capas con expansión decimal
            app.Slider.Value = valor; % Se lo asigna al Slider para que se note que está eligiendo un valor entero
            
            
            lambdaI = app.LambdaInicialEditField.Value; % Se hace la conversión con las unidades del rango inicial
            opLambda = app.LambdaInicialDesplegable.Value;
            switch opLambda 
                case 'dm'
                    lambdaI = (lambdaI*0.1);
                case 'cm'
                    lambdaI = (lambdaI*0.01);
                case 'mm'
                    lambdaI = (lambdaI*0.001);
                case 'μm'
                    lambdaI = (lambdaI*0.000001);
                case 'nm'
                    lambdaI = (lambdaI*0.000000001);
                case 'pm'
                    lambdaI = (lambdaI*0.000000000001);
                otherwise
                    lambdaI = lambdaI;
            end
            
            
            lambdaF = app.LambdaFinalEditField.Value; % Se hace la conversión con las unidades del rango final
            opLambda = app.LambdaFinalDesplegable.Value;
            switch opLambda 
                case 'dm'
                    lambdaF = (lambdaF*0.1);
                case 'cm'
                    lambdaF = (lambdaF*0.01);
                case 'mm'
                    lambdaF = (lambdaF*0.001);
                case 'μm'
                    lambdaF = (lambdaF*0.000001);
                case 'nm'
                    lambdaF = (lambdaF*0.000000001);
                case 'pm'
                    lambdaF = (lambdaF*0.000000000001);
                otherwise
                    lambdaF = lambdaF;
            end


            longitud = app.LongitudporcapaEditField.Value; % Se hace la conversión con las unidades de longitud 
            opLongitud = app.LongitudDesplegable.Value;            
            switch opLongitud 
                case 'dm'
                    longitud = (longitud*0.1); 
                case 'cm'
                    longitud = (longitud*0.01);  
                case 'mm'
                    longitud = (longitud*0.001);
                case 'μm'
                    longitud = (longitud*0.000001);   
                case 'nm'
                    longitud = (longitud*0.000000001);   
                case 'pm'
                    longitud = (longitud*0.000000000001);
            end
            
            
            p=mod(valor,2); % Variable que controla si está en una capa par o impar
            
            
            %%%%%%%%%%%%%%%%%%%%% Fin Declaración de variables %%%%%%%%%%%%%%%%%%%%%%%%%
 
            
            function salida = PotenciaR(x) % Para poder ser usado por el fplot necesita tener solo una variable de entrada. 
                % La ventaja de tenerlo como método en este "scope" es que tiene acceso a todas las variables en este evento.
                
                if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
                    etaEQ = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*medio1*tan(((2*pi)/x)*longitud)));
                    
                else % Es par (La primera es n1, y la última n2)
                    etaEQ = medio1*((medio2+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*medio2*tan(((2*pi)/x)*longitud)));   
                end
                
                gamma = (medio2-medio1)/(medio2+medio1);
                Pr = gamma^2;
                Pt = (1-gamma^2);
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin Set-Up inicial %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                for n = (valor-3):-1:0 % Con esta parte, itero la ecuación del medio equivalente
                    % Le resto 3 al valor, ya que en el setup inicial calculé para 2 medios
                    
                    if(n==0) % ¿Estamos simplificando las últimas 2 capas? Calcule los parámetros de interés
                        gamma = (etaEQ-medio1)/(etaEQ+medio1);
                        Pr = abs(gamma)^2;
                        Pt = (1-abs(gamma)^2);
                        salida = Pr;
                    end
                    
                    if((p==1)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
                        etaEQ = medio2*((etaEQ+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=0; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                    
                    if((p==0)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
                        etaEQ = medio1*((etaEQ+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=1; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                end
                
                p=mod(valor,2); % Reinicia la variable que controla si está en una capa par o impar
                
                app.Prueba.Value = "Medio1: "+medio1+          newline                      +"Medio2: "+medio2+           newline                     +"Slider: "+valor+'                                      '+'                                            '+"Longitud: "+longitud+' m; OpLong: '+opLongitud+            newline      +          newline          +"Para λ: "+ x + newline + "etaEQ: "+etaEQ+   newline    + "Gamma: "+gamma+   newline     +"Pr: "+Pr+    newline   +"Pt: "+Pt;
                
                salida = Pr;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin del núcleo multicapa reflejado %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            end
            
            
            function salida = PotenciaT(x) % Para poder ser usado por el fplot necesita tener solo una variable de entrada. 
                % La ventaja de tenerlo como método en este "scope" es que tiene acceso a todas las variables en este evento.
                
                if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
                    etaEQ = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*medio1*tan(((2*pi)/x)*longitud)));
  
                else % Es par (La primera es n1, y la última n2)
                    etaEQ = medio1*((medio2+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*medio2*tan(((2*pi)/x)*longitud))); 
                end
                
                gamma = (medio2-medio1)/(medio2+medio1);
                Pr = gamma^2;
                Pt = (1-gamma^2);
                

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin Set-Up inicial %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                for n = (valor-3):-1:0 % Con esta parte, itero la ecuación del medio equivalente
                    % Le resto 3 al valor, ya que en el setup inicial calculé para 2 medios
                    
                    if(n==0) % ¿Estamos simplificando las últimas 2 capas? Calcule los parámetros de interés
                        gamma = (etaEQ-medio1)/(etaEQ+medio1);
                        Pr = abs(gamma)^2;
                        Pt = (1-abs(gamma)^2);
                        salida = Pt;
                    end
                    
                    if((p==1)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
                        etaEQ = medio2*((etaEQ+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=0; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                    
                    if((p==0)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
                        etaEQ = medio1*((etaEQ+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=1; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                end
                
                p=mod(valor,2); % Reinicia la variable que controla si está en una capa par o impar
                
                app.Prueba.Value = "Medio1: "+medio1+          newline                      +"Medio2: "+medio2+           newline                     +"Slider: "+valor+'                                      '+'                                            '+"Longitud: "+longitud+' m; OpLong: '+opLongitud+            newline      +          newline          +"Para λ: "+ x + newline + "etaEQ: "+etaEQ+   newline    + "Gamma: "+gamma+   newline     +"Pr: "+Pr+    newline   +"Pt: "+Pt;
                
                salida = Pt;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin del núcleo multicapa %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            end
            
                
            %%%%%%%%%%%%%%%%%%%%% Fin funciones de cálculo %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ax = app.UIAxes;
            f = @PotenciaR; % Pr focused
            f2 = @PotenciaT; % Pt focused


            if(app.GraficasButtonGroup.SelectedObject == app.PrButton) % Se eligió graficar P
                
                grid(ax,'on') % Encender los ejes
                fplot(ax,f,[lambdaI lambdaF],'Color','r','LineWidth',0.75);
            
            elseif(app.GraficasButtonGroup.SelectedObject == app.PtButton)
               
                grid(ax,'on') % Encender los ejes
                fplot(ax,f2,[lambdaI lambdaF],'Color','b','LineWidth',0.75);
                
            else % Se eligió "Ambas"
                
                grid(ax,'on') % Encender los ejes
                fplot(ax,f,[lambdaI lambdaF],'Color','r','LineWidth',0.75);
                hold(app.UIAxes,'on');
                fplot(ax,f2,[lambdaI lambdaF],'Color','b','LineWidth',0.75);
                hold(ax,'off');
                
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%% Fin del módulo de graficado %%%%%%%%%%%%%%%%%%%%%%
        end

        % Close request function: 
        % FiltrospticosdemediosmulticapaLayeredMediaUIFigure
        function FiltrospticosdemediosmulticapaLayeredMediaUIFigureCloseRequest(app, event)
            selection = questdlg('¿Está seguro que desea cerrar el programa?', 'Cerrar el programa', 'Sí', 'No', 'No');
            switch selection
                case 'Sí'
                    delete(app)
                    
                case 'No'
                    return
            end
            delete(app)
%             delete(app)
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            f = msgbox({'En esta sección, se pretende explicar los parámetros que podrían parecer confusos. Por ejemplo:' ; '';'El número de capas, se refiere a la cantidad de medios que hay disponibles para llegar a solamente un medio de impedancia intrínseca equivalente.';'';'Los valores de lambda inicial y final, son los límites inferior y superior respectivamente con el que se va a ejecutar el barrido en longitud de onda a través del arreglo de medios.'; '';'Reset es un botón que lleva de vuelta a la posición original del gráfico.'},'Ayuda','help');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            valor = app.Slider.Value;
            valor = round(valor); % Lo redondea, ya que no hay capas con expansión decimal
            app.Slider.Value = valor; % Se lo asigna al Slider para que se note que está eligiendo un valor entero
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset(app.UIAxes); % Reset completo
            
            app.UIAxes.Title.String = 'Espectro de Potencia';
            app.UIAxes.XLabel.String = '\lambda [m]';
            app.UIAxes.YLabel.String = 'Pr (rojo)      Pt (azul)   [W]';
            app.UIAxes.XGrid = 'on';
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.YTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            app.UIAxes.TickDir = 'in';
            app.UIAxes.TickDirMode = 'auto';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiltrospticosdemediosmulticapaLayeredMediaUIFigure = uifigure;
            app.FiltrospticosdemediosmulticapaLayeredMediaUIFigure.Name = 'Filtros ópticos de medios multicapa [ Layered Media ]';
            app.FiltrospticosdemediosmulticapaLayeredMediaUIFigure.CloseRequestFcn = createCallbackFcn(app, @FiltrospticosdemediosmulticapaLayeredMediaUIFigureCloseRequest, true);
            app.Panel = uipanel(app.FiltrospticosdemediosmulticapaLayeredMediaUIFigure);
            app.Panel.Title = ' ';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Espectro de Potencia')
            xlabel(app.UIAxes, '\lambda [m]')
            ylabel(app.UIAxes, 'Pr (rojo)      Pt(azul)   [W]')
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.LongitudesButtonGroup = uibuttongroup(app.ButtonGroup);
            app.LongitudesButtonGroup.Title = 'Longitudes de onda y capa';
            app.LambdaInicialEditField = uieditfield(app.LongitudesButtonGroup, 'numeric');
            app.LambdaInicialEditField.Value = 1;
            app.LambdaFinalEditField = uieditfield(app.LongitudesButtonGroup, 'numeric');
            app.LambdaFinalEditField.Value = 1500;
            app.LongitudDesplegable = uidropdown(app.LongitudesButtonGroup);
            app.LongitudDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LongitudDesplegable.Value = 'm';
            app.Valorde_inicialLabel = uilabel(app.LongitudesButtonGroup);
            app.Valorde_inicialLabel.Text = 'Valor de λ_inicial';
            app.LambdaFinalDesplegable = uidropdown(app.LongitudesButtonGroup);
            app.LambdaFinalDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LambdaFinalDesplegable.Value = 'm';
            app.Valorde_finalLabel = uilabel(app.LongitudesButtonGroup);
            app.Valorde_finalLabel.Text = 'Valor de λ_final';
            app.LambdaInicialDesplegable = uidropdown(app.LongitudesButtonGroup);
            app.LambdaInicialDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LambdaInicialDesplegable.Value = 'm';
            app.LongitudporcapaLabel = uilabel(app.LongitudesButtonGroup);
            app.LongitudporcapaLabel.Text = 'Longitud por capa';
            app.LongitudporcapaEditField = uieditfield(app.LongitudesButtonGroup, 'numeric');
            app.LongitudporcapaEditField.Value = 40;
            app.GraficasButtonGroup = uibuttongroup(app.ButtonGroup);
            app.GraficasButtonGroup.Title = 'Gráficas de espectro a mostrar';
            app.PrButton = uiradiobutton(app.GraficasButtonGroup);
            app.PrButton.Text = 'Pr';
            app.PtButton = uiradiobutton(app.GraficasButtonGroup);
            app.PtButton.Text = 'Pt';
            app.AmbasButton = uiradiobutton(app.GraficasButtonGroup);
            app.AmbasButton.Text = 'Ambas';
            app.AmbasButton.Value = true;
            app.GraficarButton = uibutton(app.GraficasButtonGroup, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.AyudaButton = uibutton(app.GraficasButtonGroup, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.ResetButton = uibutton(app.GraficasButtonGroup, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CantidaddecapasSliderLabel = uilabel(app.ButtonGroup);
            app.CantidaddecapasSliderLabel.Text = {'Cantidad '; 'de capas'};
            app.Slider = uislider(app.ButtonGroup);
            app.Slider.Limits = [2 25];
            app.Slider.MajorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.MajorTickLabels = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 5;
            app.DatosadicionalesLabel = uilabel(app.ButtonGroup);
            app.DatosadicionalesLabel.Text = 'Datos adicionales';
            app.Prueba = uitextarea(app.ButtonGroup);
            app.Prueba.Editable = 'off';
            app.ImpedanciasintrnsecasButtonGroup = uibuttongroup(app.ButtonGroup);
            app.ImpedanciasintrnsecasButtonGroup.Title = 'Impedancias intrínsecas';
            app.Label_4 = uilabel(app.ImpedanciasintrnsecasButtonGroup);
            app.Label_4.Text = 'Ω';
            app.Label_5 = uilabel(app.ImpedanciasintrnsecasButtonGroup);
            app.Label_5.Text = 'Ω';
            app.Medio1EditFieldLabel = uilabel(app.ImpedanciasintrnsecasButtonGroup);
            app.Medio1EditFieldLabel.Text = 'Medio η1';
            app.Medio1EditField = uieditfield(app.ImpedanciasintrnsecasButtonGroup, 'numeric');
            app.Medio1EditField.Value = 343;
            app.Medio2EditFieldLabel = uilabel(app.ImpedanciasintrnsecasButtonGroup);
            app.Medio2EditFieldLabel.Text = 'Medio η2';
            app.Medio2EditField = uieditfield(app.ImpedanciasintrnsecasButtonGroup, 'numeric');
            app.Medio2EditField.Value = 2048;
        end
    end

    methods (Access = public)

        % Construct app
        function app = LayeredMedia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiltrospticosdemediosmulticapaLayeredMediaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiltrospticosdemediosmulticapaLayeredMediaUIFigure)
        end
    end
end